classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        OfflineChatLabel  matlab.ui.control.Label
        MessageInput      matlab.ui.control.EditField
        ChatLabel         matlab.ui.control.Label
        ChatArea          matlab.ui.control.TextArea
        OutputsLabel      matlab.ui.control.Label
        DeviceList        matlab.ui.control.ListBox
        DevicesLabel      matlab.ui.control.Label
        ConnectButton     matlab.ui.control.Button
        SendButton        matlab.ui.control.Button
        ScanButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        btObject
        % Description
    end
    
    methods (Access = private)

        % Bu fonksiyonu olduğu gibi yapıştır
        function readData(app, src, event)
            if src.BytesAvailable > 0
                % Gelen veriyi oku
                gelenMesaj = readline(src);
                
                % Ekrana yaz (String'e çevirerek hata riskini azaltıyoruz)
                oldChat = app.ChatArea.Value;
                yeniSatir = "FRIEND: " + string(gelenMesaj);
                
                app.ChatArea.Value = [oldChat; yeniSatir];
            end
        end

    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.ChatArea.Value = "Scanning...";
            drawnow;
            
            listItems = {}; % Boş liste
            
            try
                % 1. Bluetooth Cihazlarını Bul (Client için)
                info = instrhwinfo('Bluetooth'); 
                if ~isempty(info.RemoteNames)
                    listItems = [listItems, info.RemoteNames'];
                end
                
                % 2. Yerel COM Portlarını Bul (Server için)
                serialPorts = serialportlist("available");
                if ~isempty(serialPorts)
                    listItems = [listItems, serialPorts];
                end
                
                % Listeyi güncelle
                if isempty(listItems)
                    app.DeviceList.Items = {"Device/Port Not Found"};
                else
                    app.DeviceList.Items = listItems;
                end
                
                app.ChatArea.Value = "Scanning is complete. Select the device or COM port.";
                
            catch
                app.ChatArea.Value = "Error: A problem occurred during scanning.";
            end
        
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
           selection = app.DeviceList.Value;
            
            if strcmp(selection, "Device/Port Not Found")
                return;
            end

            app.ChatArea.Value = "Connecting: " + selection;
            drawnow;
            
            try
                % --- AYRIM NOKTASI ---
                if startsWith(selection, "COM")
                    % Server Modu (Serial)
                    app.btObject = serialport(selection, 9600); 
                else
                    % Client Modu (Bluetooth)
                    app.btObject = bluetooth(selection, 4);
                end
                
                % --- YENİ EKLENEN KISIM (Kritik Düzeltme) ---
                % Her iki tarafın da satır sonunu "CR/LF" olarak anlamasını sağla
                configureTerminator(app.btObject, "CR/LF");
                % --------------------------------------------
                
                if ~isa(app.btObject, 'matlab.io.SerialPort')
                     fopen(app.btObject); 
                end

                configureCallback(app.btObject, "terminator", @(src,evt)readData(app, src, evt));
                
                app.ChatArea.Value = "CONNECTED! (" + selection + ")";
            catch me
                app.ChatArea.Value = "Connection Error: " + me.message;
            end
        
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            msg = app.MessageInput.Value; % Yazılanı al
            
            if ~isempty(app.btObject) % Bağlantı var mı?
                writeline(app.btObject, msg); % Karşıya gönder
                
                % Kendi ekranımıza da ekleyelim
                oldChat = app.ChatArea.Value;
                app.ChatArea.Value = [oldChat; "ME: " + msg];
                
                app.MessageInput.Value = ""; % Kutuyu temizle
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan Devices';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DevicesLabel = uilabel(app.UIFigure);
            app.DevicesLabel.Text = 'Devices';
            app.DeviceList = uilistbox(app.UIFigure);
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.ChatArea = uitextarea(app.UIFigure);
            app.ChatLabel = uilabel(app.UIFigure);
            app.ChatLabel.Text = 'Chat';
            app.MessageInput = uieditfield(app.UIFigure, 'text');
            app.OfflineChatLabel = uilabel(app.UIFigure);
            app.OfflineChatLabel.Text = 'Offline Chat';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end